classdef PlantMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EnviromentMonitorUIFigure    matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TestBoardButton              matlab.ui.control.Button
        TabGroup                     matlab.ui.container.TabGroup
        SensorsTab                   matlab.ui.container.Tab
        SensorDataButtonGroup        matlab.ui.container.ButtonGroup
        SoundSensorButton            matlab.ui.control.RadioButton
        TemperatureSensorButton      matlab.ui.control.RadioButton
        LightSensorButton            matlab.ui.control.RadioButton
        HumiditySensorButton         matlab.ui.control.RadioButton
        MoistureSensorButton         matlab.ui.control.RadioButton
        TestBoardTab                 matlab.ui.container.Tab
        RedLEDButton                 matlab.ui.control.Button
        GreenLEDButton               matlab.ui.control.Button
        BuzzerButton                 matlab.ui.control.Button
        BuzzerIntensityKnobLabel     matlab.ui.control.Label
        IntensityKnob                matlab.ui.control.Knob
        CalibrationTab               matlab.ui.container.Tab
        NoiseLeveldBLabel            matlab.ui.control.Label
        NoiseLeveldBEditField        matlab.ui.control.EditField
        LightLevelLUXEditFieldLabel  matlab.ui.control.Label
        LightLevelLUXEditField       matlab.ui.control.EditField
        RoomTempCLabel               matlab.ui.control.Label
        RoomTempCEditField           matlab.ui.control.EditField
        InputValuesButton            matlab.ui.control.Button
        CalibrationButton            matlab.ui.control.Button
        AutoCalibrateButton          matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        DurationSlidersLabel         matlab.ui.control.Label
        DurationSlider               matlab.ui.control.Slider
        ValueEditFieldLabel          matlab.ui.control.Label
        ValueEditField               matlab.ui.control.NumericEditField
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        ActionsButtonGroup           matlab.ui.container.ButtonGroup
        ClearNoiseButton             matlab.ui.control.Button
        FilterLabel                  matlab.ui.control.Label
        FilterEditField              matlab.ui.control.NumericEditField
        DerivativeButton             matlab.ui.control.Button
        MeanButton                   matlab.ui.control.Button
        LineofBestFitButton          matlab.ui.control.Button
        DegreeEditFieldLabel         matlab.ui.control.Label
        DegreeEditField              matlab.ui.control.NumericEditField
        MeanofResidualErrorButton    matlab.ui.control.Button
        ErrorLabel                   matlab.ui.control.Label
        ErrorEditField               matlab.ui.control.NumericEditField
        PlotButton                   matlab.ui.control.Button
        ClearPlotButton              matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
          
          set(app.ClearNoiseButton, 'Enable', 'on')
          % enables clear noise button 
          
          set(app.FilterEditField, 'Enable', 'on')
          % enables the num field to enter filter value
          
          set(app.DerivativeButton, 'Enable', 'on')
          % enables derivative button
           
          set(app.MeanButton, 'Enable', 'on')
          % enables the mean button
           
          set(app.LineofBestFitButton, 'Enable', 'on')
          % enables the median button
           
          set(app.ClearPlotButton, 'Enable', 'on')
          % enables the clear plot button
          
          set(app.DegreeEditField, 'Enable', 'on')
           % enables the degree field
          
          set(app.ErrorEditField, 'Enable', 'on')
          % enables the error field button
           
          set(app.MeanofResidualErrorButton, 'Enable', 'on')
          % enables the residual error button
            
          if(app.SoundSensorButton.Value == true ) 
              % if sound sensor selected
              
              soundCal = app.NoiseLeveldBEditField.Value;
              %assigns calibration value from workspace
              
              assignin('base','pinnum', 2) 
              % assigns pin to sound sensor pin for the simulink model
              
              simout = sim('SensorModel', 'StopTime', num2str(app.DurationSlider.Value));
              % runs the sensor model simulation to get sound sensor values
              
              x = simout.tout; 
              % Assigns time to x variable
              
              y = simout.Results;
              % Assigns Sound Sensor values to y variable
                
              %y = double(y);
              % Converts y to double from unit16 to calibrate
              
              %if(soundCal > 0) % Sets Manual Calibration of sensor
                    
                  %y = y./soundCal; 
                  % Calibrated values relative to room
              
              %else
                  y = y/10; 
                  % Auto Calibration to db 
                  %end
                  
              
              assignin('base','x',simout.tout) 
              % Assigns time to x variable in workspace
              
              assignin('base','y',y) 
              % Assigns sounds results to y variable in workspace
              
              app.UIAxes.XLabel.String = ('Time (s)');
              % Label x axis with time

              app.UIAxes.YLabel.String = ('Sound Sensor Instensity (dB)');
              % Label y axis with sound sensor voltage

              app.UIAxes.Title.String = ('Sound Sensor Intensity vs Time');
              % Label title with sound sensor instensity vs time
              
              plot(app.UIAxes,x,y)
              % Plot sound sensor variable against time
              
              legend(app.UIAxes, 'Sound Data') 
              % Label legend
          end 
          
           if(app.LightSensorButton.Value == true )
              % if light sensor selected
              
              lightCal = app.LightLevelLUXEditField.Value;
              %assigns calibration value from workspace
              
              assignin('base','pinnum', 6)
              % assigns pin to sound sensor pin for the simulink model

              simout = sim('SensorModel', 'StopTime', num2str(app.DurationSlider.Value));
              % runs the sensor model simulation to get light sensor values

              x = simout.tout;
              % Assigns time to x variable
              y = simout.Results; 
              % Assigns Light Sensor Values to y variable
             
              y = double(y);
              % Converts y to double from unit16 to calibrate
              
             if(lightCal > 0) % Sets Manual Calibration of sensor
                    
                  y = y./lightCal; 
                  % Calibrated values relative to room
              
              else
                  y = y*.28; 
                  % Auto Calibration to db 
              end
              
              assignin('base','x',simout.tout) 
              % Assigns time to x variable in workspace
              
              assignin('base','y',y) 
              % Assigns light results to y variable in workspace
              
              app.UIAxes.XLabel.String = ('Time (s)');
              % Label x axis with time
              
              app.UIAxes.YLabel.String = ('Light Intnesity');
              % Label y axis with light sensor 
                  
              app.UIAxes.Title.String = ('Light Sensor Intensity vs Time');
              % Label title with light sensor intensity vs time
              
              plot(app.UIAxes,x,y)
              % Plot light sensor variable against time  
              
              legend(app.UIAxes, 'Light Data') 
              % Label legend
           end 
          
            if(app.TemperatureSensorButton.Value == true )
              % if Temp sensor selected

              tempCal = app.RoomTempCEditField.Value;
              %assigns calibration value from workspace
              
              assignin('base','pinnum', 1)
              % assigns pin to Temp sensor pin for the simulink model

              simout = sim('SensorModel', 'StopTime', num2str(app.DurationSlider.Value));
              % runs the sensor model simulation to get light sensor values

              x = simout.tout;
              % Assigns time to x variable
              y = simout.Results;
              
              y = double(y);
              % Converts y to double from unit16 to calibrate
              
             if(tempCal > 0) % Sets Manual Calibration of sensor
                    
                  y = (y* 5.0 / 1024) * 10 -18 + tempCal; 
                  % Calibrated values relative to room (to degrees)
              
              else
                  y = (y* 5.0 / 1024) * 10 + 21; 
                  % Auto Calibration to (to degrees)
              end
              
              assignin('base','x',simout.tout) 
              % Assigns time to x variable in workspace
              
              assignin('base','y',y) 
              % Assigns sounds results to x variable in workspace
              
              app.UIAxes.XLabel.String = ('Time (s)');
              % Label x axis with time
              
              app.UIAxes.YLabel.String = ('Temperature (°C)');
              % Label y axis with temp   
              
              app.UIAxes.Title.String = ('Temperature vs Time');
              % Label title with temp vs time
              
              plot(app.UIAxes,x,y)
              % Plot light sensor variable against time
              
              legend(app.UIAxes, 'Temperature Data') 
              % Label legend
           end 
           
            if(app.HumiditySensorButton.Value == true )
              % if Temp sensor selected

             a = arduino('COM3', 'Nano3', 'Libraries', 'Adafruit/DHT22');
             sensor = addon(a, 'Adafruit/DHT22', 'D3');
             % Board and sensor intialization vars
             
             tic; % Start time
             time = 0; % Intialize time
             humidity = 0; % Intialize Time 
             
            for(i = 1:88*app.DurationSlider.Value) 
                % calibration based on how long it takes to plot one point
                
                humidity(i) = readHumidity(sensor); % Get humidity
                time(i) = toc; % Get time   
            end
                
  
              x = time;
              % Assigns time to x variable
              y = humidity;
              % Assigns humidity Sensor Values to y variable 
              
              assignin('base','x',time) 
              % Assigns time to x variable in workspace
              
              assignin('base','y',humidity) 
              % Assigns humidity results to y variable in workspace
              
              app.UIAxes.XLabel.String = ('Time (s)');
              % Label x axis with humidity
              
              app.UIAxes.YLabel.String = ('Humdity (%)');
              % Label y axis with humidity 
              
              app.UIAxes.Title.String = ('Humidty vs Time');
              % Label title with humidity vs time
              
               plot(app.UIAxes,x,y)
              % Plot humidity sensor variable against time       
              
              legend(app.UIAxes, 'Humidity Data') 
              % Label legend
              
            end 
            if(app.MoistureSensorButton.Value == true )
              % if moisture sensor selected

              assignin('base','pinnum', 7)
              % assigns pin to mositure sensor pin for the simulink model

              simout = sim('SensorModel', 'StopTime', num2str(app.DurationSlider.Value));
              % runs the sensor model simulation to get light sensor values

              x = simout.tout;
              % Assigns time to x variable
              y = simout.Results; 
              % Assigns moisture Sensor Values to y variable
             
              assignin('base','x',simout.tout) 
              % Assigns time to x variable in workspace
              
              assignin('base','y',simout.Results) 
              % Assigns moisture results to y variable in workspace
              
              app.UIAxes.XLabel.String = ('Time (s)');
              % Label x axis with time
              
              app.UIAxes.YLabel.String = ('Moisture Sensor Level');
              % Label y axis with moisture sensor 
              
              app.UIAxes.Title.String = ('Moisture Sensor Level vs Time');
              % Label title with moisture sensor vs time
              
              plot(app.UIAxes,x,y)
              % Plot moisture sensor variable against time      
              
              legend(app.UIAxes, 'Moisture Data') 
              % Label legend
           end 
          
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EnviromentMonitorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {487, 487, 487};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {487, 487};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {313, '1x', 197};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: ClearNoiseButton
        function ClearNoiseButtonPushed(app, event)
                 
           x = evalin('base','x'); % Retrives x variable from workspace
           y = evalin('base','y'); % Retrives y variable from workspace
            
           filter = app.FilterEditField.Value; % Gets value from textfield
           filtered = movmean(y,filter); % Applies filter on the data
           
           plot(app.UIAxes,x,filtered) % Plots the new filtered data
           
                
          if(app.SoundSensorButton.Value == true ) 
              % if sound sensor selected

              legend(app.UIAxes, 'Sound Data') 
              % Label legend
          end 
          
           if(app.LightSensorButton.Value == true )
              % if light sensor selected

              legend(app.UIAxes, 'Light Data') 
              % Label legend
           end 
          
            if(app.TemperatureSensorButton.Value == true )
              % if Temp sensor selected

              legend(app.UIAxes, 'Temperature Data') 
              % Label legend
           end 
           
            if(app.HumiditySensorButton.Value == true )
              % if Temp sensor selected

              legend(app.UIAxes, 'Humidity Data') 
              % Label legend
              
            end 
            if(app.MoistureSensorButton.Value == true )
              % if moisture sensor selected

              legend(app.UIAxes, 'Moisture Data') 
              % Label legend
           end 
           
        end

        % Button pushed function: ClearPlotButton
        function ClearPlotButtonPushed(app, event)
           cla(app.UIAxes)  
           % Clears the exisiting plot
           
           set(app.ClearNoiseButton, 'Enable', 'off')
           % disables clear noise button 

           set(app.FilterEditField, 'Enable', 'off')
           % disables the num field to enter filter value
           
           set(app.DerivativeButton, 'Enable', 'off')
           % disables derivative button
           
            set(app.MeanButton, 'Enable', 'off')
           % disables the mean button
           
           set(app.ClearPlotButton, 'Enable', 'off')
           % disables the clear plot button
           
            set(app.DegreeEditField, 'Enable', 'off')
           % disables the degree field
           
           set(app.LineofBestFitButton, 'Enable', 'off')
           % disables the best fit button button
           
           set(app.ErrorEditField, 'Enable', 'off')
           % disables the error field button
           
           set(app.MeanofResidualErrorButton, 'Enable', 'off')
           % disables the residual error button
           
           app.FilterEditField.Value = 1;
           %Reset the filter value
           
           app.DegreeEditField.Value = 1;
           %Reset the filter value
           
           app.UIAxes.XLabel.String = ('');
           app.UIAxes.YLabel.String = ('');
           app.UIAxes.Title.String = ('');
           % Clear x and y labels and title of plot
        end

        % Button pushed function: TestBoardButton
        function TestBoardButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TestBoardTab;
            %Display the test board tab
            
        end

        % Button pushed function: RedLEDButton
        function RedLEDButtonPushed(app, event)
            a = arduino('COM3', 'uno');
            
            writeDigitalPin(a,'D4', 1) 
            % Turn on the red Led D4
            pause(5)
            writeDigitalPin(a,'D4', 0)
            % Turn off the red Led D4
            a = 0;
            % Reset the a variable
        end

        % Button pushed function: GreenLEDButton
        function GreenLEDButtonPushed(app, event)
            a = arduino('COM3', 'uno');
            
            writeDigitalPin(a,'D13', 1) 
            % Turn on the green Led D13
            pause(5)
            writeDigitalPin(a,'D13', 0)
            % Turn off the green Led D13
            a = 0;
            % Reset the a variable
        end

        % Button pushed function: BuzzerButton
        function BuzzerButtonPushed(app, event)
            a = arduino('COM3', 'uno');
            
            writePWMDutyCycle(a, 'D5', app.IntensityKnob.Value/100);
            % Turn on the buzzer D5
            pause(5)
            writeDigitalPin(a,'D4', 0)
            % Turn off the buzzer D5
            a = 0;
            % Reset the a variable
        end

        % Button pushed function: DerivativeButton
        function DerivativeButtonPushed(app, event)
           x = evalin('base','x'); % Retrives x variable from workspace
           y = evalin('base','y'); % Retrives y variable from workspace
            
            
            dy= (double(diff(y(:)))./diff(x(:)))/25;           
            % Calculates the gradient or change in y over x with space
            % between point of 1
            x= (1:length(dy))/100 + 1; % Assign points through the length of dy
           
           plot(app.UIAxes,x,dy); % Plots the new filtered data
           legend(app.UIAxes, 'Derivative', 'r'); 

         
        end

        % Callback function
        function StandardDeviationButtonPushed(app, event)
           % Didnt Pass Testing Phase, I did not include it in my
           % application
           
            x = evalin('base','x'); % Retrives x variable from workspace
            y = evalin('base','y'); % Retrives y variable from workspace
            
            plot(x,y) % Plotting the points obtained beofre
            hold on 
            
            y= double(y); % Converting y from unit16 to double
           
            std_y = std(y);
            % data + 1 std deviation (above)
            
            y_plus = (y + std_y)';
            % data - 1 std deviation (below)
            
            y_minus = (y - std_y)';
            % create a vector of the avg-std_dev
            
            j=1;
            for i = length(y):-1:1
             y_minus_reverse(j) = y_minus(i);
             j=j+1; % increment j positive
            end
            
            % plot std deviation shape
            patch([1:1:length(y) length(y):-1:1],[y_plus y_minus_reverse],...
             'b',...
             'facealpha',0.05,...% fill colour
             'edgecolor','r',...
             'edgealpha',0.05) % edge colour
            
           
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.CalibrationTab;
            %Display the calibrate board tab
        end

        % Button pushed function: InputValuesButton
        function InputValuesButtonPushed(app, event)
           soundCal = app.NoiseLeveldBEditField.Value;
           % Assigns the soundCal var to the ambient noise level
           
           tempCal = app.RoomTempCEditField.Value;
           % Assigns the soundCal var to the ambient noise level

           lightCal = app.LightLevelLUXEditField.Value;
           % Assigns the soundCal var to the ambient noise level

           assignin("base", "soundCal",soundCal)
           assignin("base", "tempCal",tempCal)  
           assignin("base", "lightCal",lightCal)
           % Assings the calibration values to workspace based on my 
           % enviroment and testing 
           
            app.TabGroup.SelectedTab = app.SensorsTab;
            %Display the sensors tab
        end

        % Button pushed function: AutoCalibrateButton
        function AutoCalibrateButtonPushed(app, event)
           assignin("base", "soundCal", 10)
           assignin("base", "tempCal", 2.8)  
           assignin("base", "lightCal", 2.8)
           % Assings the calibration values to workspace 
        end

        % Button pushed function: MeanButton
        function MeanButtonPushed(app, event)
            x = evalin("base", 'x');
            % Retrive x variable from workspace

            y = evalin("base", 'y');
            % Retrive y variable from workspace
            
            avg_y_scalar = mean(y);
            % avgy scalar assigned to the mean of arra y 
            
            avg_y_vector = avg_y_scalar * ones(1,length(y));
            
            hold(app.UIAxes, "on");
            % Keeps the previous function on the graph
            
            plot(app.UIAxes, x,avg_y_vector, 'r');
            
            legend(app.UIAxes, 'Collected Data', 'Mean'); 
            
            hold(app.UIAxes, "off");

        end

        % Value changing function: DurationSlider
        function DurationSliderValueChanging(app, event)
            changingValue = event.Value;
            app.ValueEditField.Value = changingValue;
            % Assigns the sliders value to textfield for user to see value
            
           set(app.PlotButton, 'Enable', 'on')
           % enables the clear plot button
        end

        % Value changed function: ValueEditField
        function ValueEditFieldValueChanged(app, event)
            value = app.ValueEditField.Value;
            app.DurationSlider.Value = value;
            % Changes the slider automatically 
            
           set(app.PlotButton, 'Enable', 'on')
           % enables the clear plot button
        end

        % Button pushed function: LineofBestFitButton
        function LineofBestFitButtonPushed(app, event)
           x = evalin("base", 'x');
           % Retrive x variable from workspace

           y = evalin("base", 'y');
           % Retrive y variable from workspace
           
           y = double(y);
           % Convert the y value from unit16 to double
           
           coeffs = polyfit(x,y,app.DegreeEditField.Value);
           %Calculates the coeficiants of the linear fit (shoehorn method)
          
           yfit = polyval(coeffs, x);
           % Assigns the new points of the plot
           
           resid = mean(y-yfit);
           %Calculates the mean residual difference 
           
           assignin("base","resid",resid);
           % Places the resid variable in the workspace
          
           hold(app.UIAxes, "on");
           % Keeps the previous function on the graph
           
           plot(app.UIAxes, x,yfit);
           % Plots the linear fit 
                 
           legend(app.UIAxes, 'Collected Data', 'Linear Fit'); 
           % Legend for the data
           
           hold(app.UIAxes, "off");
           % Stops keeping the previous function on the graph
        end

        % Button pushed function: MeanofResidualErrorButton
        function MeanofResidualErrorButtonPushed(app, event)
           resid = evalin("base", 'resid');
           % Retrive resid variable from workspace
           
           app.ErrorEditField.Value = resid;
           % Assigns the variable to error textfield rounded to two places
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EnviromentMonitorUIFigure = uifigure('Visible', 'off');
            app.EnviromentMonitorUIFigure.Name = 'Enviroment Monitor';
            app.EnviromentMonitorUIFigure.Icon = 'png-transparent-bar-chart-computer-icons-graph-of-a-function-others-miscellaneous-angle-text.png';
            app.EnviromentMonitorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EnviromentMonitorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TestBoardButton = uibutton(app.LeftPanel, 'push');
            app.TestBoardButton.ButtonPushedFcn = createCallbackFcn(app, @TestBoardButtonPushed, true);
            app.TestBoardButton.Text = 'Test Board';
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.SensorDataButtonGroup = uibuttongroup(app.SensorsTab);
            app.SensorDataButtonGroup.Title = 'Sensor Data';
            app.SoundSensorButton = uiradiobutton(app.SensorDataButtonGroup);
            app.SoundSensorButton.Text = 'Sound Sensor';
            app.SoundSensorButton.Value = true;
            app.TemperatureSensorButton = uiradiobutton(app.SensorDataButtonGroup);
            app.TemperatureSensorButton.Text = 'Temperature Sensor';
            app.LightSensorButton = uiradiobutton(app.SensorDataButtonGroup);
            app.LightSensorButton.Text = 'Light Sensor';
            app.HumiditySensorButton = uiradiobutton(app.SensorDataButtonGroup);
            app.HumiditySensorButton.Text = 'Humidity Sensor';
            app.MoistureSensorButton = uiradiobutton(app.SensorDataButtonGroup);
            app.MoistureSensorButton.Text = 'Moisture Sensor';
            app.TestBoardTab = uitab(app.TabGroup);
            app.TestBoardTab.Title = 'Test Board';
            app.RedLEDButton = uibutton(app.TestBoardTab, 'push');
            app.RedLEDButton.ButtonPushedFcn = createCallbackFcn(app, @RedLEDButtonPushed, true);
            app.RedLEDButton.Text = 'Red LED';
            app.GreenLEDButton = uibutton(app.TestBoardTab, 'push');
            app.GreenLEDButton.ButtonPushedFcn = createCallbackFcn(app, @GreenLEDButtonPushed, true);
            app.GreenLEDButton.Text = 'Green LED';
            app.BuzzerButton = uibutton(app.TestBoardTab, 'push');
            app.BuzzerButton.ButtonPushedFcn = createCallbackFcn(app, @BuzzerButtonPushed, true);
            app.BuzzerButton.Text = 'Buzzer';
            app.BuzzerIntensityKnobLabel = uilabel(app.TestBoardTab);
            app.BuzzerIntensityKnobLabel.Text = 'Buzzer Intensity';
            app.IntensityKnob = uiknob(app.TestBoardTab, 'continuous');
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.NoiseLeveldBLabel = uilabel(app.CalibrationTab);
            app.NoiseLeveldBLabel.Text = 'Noise Level (dB):   ';
            app.NoiseLeveldBEditField = uieditfield(app.CalibrationTab, 'text');
            app.NoiseLeveldBEditField.Value = '0';
            app.LightLevelLUXEditFieldLabel = uilabel(app.CalibrationTab);
            app.LightLevelLUXEditFieldLabel.Text = 'Light Level (LUX): ';
            app.LightLevelLUXEditField = uieditfield(app.CalibrationTab, 'text');
            app.LightLevelLUXEditField.Value = '0';
            app.RoomTempCLabel = uilabel(app.CalibrationTab);
            app.RoomTempCLabel.Text = 'Room Temp: (°C):  ';
            app.RoomTempCEditField = uieditfield(app.CalibrationTab, 'text');
            app.RoomTempCEditField.Value = '0';
            app.InputValuesButton = uibutton(app.CalibrationTab, 'push');
            app.InputValuesButton.ButtonPushedFcn = createCallbackFcn(app, @InputValuesButtonPushed, true);
            app.InputValuesButton.Text = 'Input Values';
            app.CalibrationButton = uibutton(app.LeftPanel, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Text = 'Calibration';
            app.AutoCalibrateButton = uibutton(app.LeftPanel, 'push');
            app.AutoCalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCalibrateButtonPushed, true);
            app.AutoCalibrateButton.Text = 'Auto Calibrate';
            app.CenterPanel = uipanel(app.GridLayout);
            app.DurationSlidersLabel = uilabel(app.CenterPanel);
            app.DurationSlidersLabel.Text = 'Duration Slider (s)';
            app.DurationSlider = uislider(app.CenterPanel);
            app.DurationSlider.ValueChangingFcn = createCallbackFcn(app, @DurationSliderValueChanging, true);
            app.ValueEditFieldLabel = uilabel(app.CenterPanel);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.ValueEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ValueEditField.ValueChangedFcn = createCallbackFcn(app, @ValueEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.CenterPanel);
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.ActionsButtonGroup = uibuttongroup(app.RightPanel);
            app.ActionsButtonGroup.Title = 'Actions ';
            app.ClearNoiseButton = uibutton(app.ActionsButtonGroup, 'push');
            app.ClearNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @ClearNoiseButtonPushed, true);
            app.ClearNoiseButton.Enable = 'off';
            app.ClearNoiseButton.Text = 'Clear Noise';
            app.FilterLabel = uilabel(app.ActionsButtonGroup);
            app.FilterLabel.Text = 'Filter:';
            app.FilterEditField = uieditfield(app.ActionsButtonGroup, 'numeric');
            app.FilterEditField.Enable = 'off';
            app.FilterEditField.Value = 1;
            app.DerivativeButton = uibutton(app.ActionsButtonGroup, 'push');
            app.DerivativeButton.ButtonPushedFcn = createCallbackFcn(app, @DerivativeButtonPushed, true);
            app.DerivativeButton.Enable = 'off';
            app.DerivativeButton.Text = 'Derivative';
            app.MeanButton = uibutton(app.ActionsButtonGroup, 'push');
            app.MeanButton.ButtonPushedFcn = createCallbackFcn(app, @MeanButtonPushed, true);
            app.MeanButton.Enable = 'off';
            app.MeanButton.Text = 'Mean';
            app.LineofBestFitButton = uibutton(app.ActionsButtonGroup, 'push');
            app.LineofBestFitButton.ButtonPushedFcn = createCallbackFcn(app, @LineofBestFitButtonPushed, true);
            app.LineofBestFitButton.Enable = 'off';
            app.LineofBestFitButton.Text = 'Line of Best Fit';
            app.DegreeEditFieldLabel = uilabel(app.ActionsButtonGroup);
            app.DegreeEditFieldLabel.Enable = 'off';
            app.DegreeEditFieldLabel.Text = 'Degree:';
            app.DegreeEditField = uieditfield(app.ActionsButtonGroup, 'numeric');
            app.DegreeEditField.Enable = 'off';
            app.DegreeEditField.Value = 1;
            app.MeanofResidualErrorButton = uibutton(app.ActionsButtonGroup, 'push');
            app.MeanofResidualErrorButton.ButtonPushedFcn = createCallbackFcn(app, @MeanofResidualErrorButtonPushed, true);
            app.MeanofResidualErrorButton.Enable = 'off';
            app.MeanofResidualErrorButton.Text = 'Mean of Residual Error ';
            app.ErrorLabel = uilabel(app.ActionsButtonGroup);
            app.ErrorLabel.Enable = 'off';
            app.ErrorLabel.Text = 'Error:';
            app.ErrorEditField = uieditfield(app.ActionsButtonGroup, 'numeric');
            app.ErrorEditField.Editable = 'off';
            app.ErrorEditField.Enable = 'off';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.ClearPlotButton = uibutton(app.RightPanel, 'push');
            app.ClearPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPlotButtonPushed, true);
            app.ClearPlotButton.Enable = 'off';
            app.ClearPlotButton.Text = 'Clear Plot';
            % Show the figure after all components are created
            app.EnviromentMonitorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlantMonitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EnviromentMonitorUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EnviromentMonitorUIFigure)
        end
    end
end